classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SECURETRANSACTIONSPORTALLabel  matlab.ui.control.Label
        FaceIdentifiedTextArea         matlab.ui.control.TextArea
        FaceIdentifiedTextAreaLabel    matlab.ui.control.Label
        ActionTextArea                 matlab.ui.control.TextArea
        ActionTextAreaLabel            matlab.ui.control.Label
        EnterButton                    matlab.ui.control.Button
        AccountNumberEditField         matlab.ui.control.NumericEditField
        AccountNumberEditFieldLabel    matlab.ui.control.Label
        PhoneNumberEditField           matlab.ui.control.NumericEditField
        PhoneNumberEditFieldLabel      matlab.ui.control.Label
        importButton_2                 matlab.ui.control.Button
        importButton                   matlab.ui.control.Button
        TrainednetworkfileEditField    matlab.ui.control.EditField
        TrainednetworkfileEditFieldLabel  matlab.ui.control.Label
        FaceRecognitionFileEditField   matlab.ui.control.EditField
        FaceRecognitionFileEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: importButton
        function importButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg')
            app.FaceRecognitionFileEditField.Value = file;
        end

        % Value changed function: FaceRecognitionFileEditField
        function FaceRecognitionFileEditFieldValueChanged(app, event)
            value = app.FaceRecognitionFileEditField.Value;
        end

        % Value changed function: TrainednetworkfileEditField
        function TrainednetworkfileEditFieldValueChanged(app, event)
            value = app.TrainednetworkfileEditField.Value;
        end

        % Button pushed function: importButton_2
        function importButton_2Pushed(app, event)
            [filename,pathname]= uigetfile('*.mat');
            app.TrainednetworkfileEditField.Value = filename;
        end

        % Value changed function: PhoneNumberEditField
        function PhoneNumberEditFieldValueChanged(app, event)
            value = app.PhoneNumberEditField.Value;
        end

        % Value changed function: AccountNumberEditField
        function AccountNumberEditFieldValueChanged(app, event)
            value = app.AccountNumberEditField.Value;
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            [~,~,raw] = xlsread("Book1.csv");
            net = load(app.TrainednetworkfileEditField.Value);
            image = app.FaceRecognitionFileEditField.Value;
            acnum = string(app.AccountNumberEditField.Value);
            phnum = string(app.PhoneNumberEditField.Value);
            B = string(raw);
            I = imread(image,'jpg');
            G = imresize(I, [224, 224]);
            importdata("Book1.csv");
            [Label, Prob] = classify(net.net,G);
            S1 = string(Label);
            IndexC = strfind(B,S1);
            Index = find(not(cellfun('isempty',IndexC)));
            Entry = B(Index,:);
            if strcmp(S1,Entry{1})==1
                if strcmp(phnum,Entry{2})==1
                    if strcmp(acnum,Entry{3})==1
                        r = 1;
                    else 
                        r = 0;
                    end
                else
                    r=0;
                end
            else
                r= 0;
            end
            %Index = find(not(contains(IndexC)));
            t = figure;
            imshow(G);
            title({char(Label), num2str(max(Prob),2)});
            uiwait(t)
            close all
            dg = double(Prob);
            if max(dg) > 0.84
                app.FaceIdentifiedTextArea.Value = S1;
                if r==1
                display = msgbox((S1+" Can proceed with transaction "),"replace");
                app.ActionTextArea.Value = S1+" Can proceed with transaction ";
                else
                display = msgbox("WRONG CREDENTIALS!! TRANSACTION BLOCKED","replace");
                app.ActionTextArea.Value = "WRONG CREDENTIALS!! TRANSACTION BLOCKED";
                end
            else
                app.FaceIdentifiedTextArea.Value = "----";
                display = msgbox("Invalid Access Face not in Database!!","replace");
                app.ActionTextArea.Value = "Invalid Access Face not in Database!!";
                       
            end
            
        end

        % Value changed function: ActionTextArea
        function ActionTextAreaValueChanged(app, event)
            value = app.ActionTextArea.Value;
            
        end

        % Value changed function: FaceIdentifiedTextArea
        function FaceIdentifiedTextAreaValueChanged(app, event)
            value = app.FaceIdentifiedTextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FaceRecognitionFileEditFieldLabel = uilabel(app.UIFigure);
            app.FaceRecognitionFileEditFieldLabel.Text = 'Face Recognition File';
            app.FaceRecognitionFileEditField = uieditfield(app.UIFigure, 'text');
            app.FaceRecognitionFileEditField.ValueChangedFcn = createCallbackFcn(app, @FaceRecognitionFileEditFieldValueChanged, true);
            app.TrainednetworkfileEditFieldLabel = uilabel(app.UIFigure);
            app.TrainednetworkfileEditFieldLabel.Text = 'Trained network file';
            app.TrainednetworkfileEditField = uieditfield(app.UIFigure, 'text');
            app.TrainednetworkfileEditField.ValueChangedFcn = createCallbackFcn(app, @TrainednetworkfileEditFieldValueChanged, true);
            app.importButton = uibutton(app.UIFigure, 'push');
            app.importButton.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.importButton.Text = 'import';
            app.importButton_2 = uibutton(app.UIFigure, 'push');
            app.importButton_2.ButtonPushedFcn = createCallbackFcn(app, @importButton_2Pushed, true);
            app.importButton_2.Text = 'import';
            app.PhoneNumberEditFieldLabel = uilabel(app.UIFigure);
            app.PhoneNumberEditFieldLabel.Text = 'Phone Number';
            app.PhoneNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhoneNumberEditField.ValueChangedFcn = createCallbackFcn(app, @PhoneNumberEditFieldValueChanged, true);
            app.AccountNumberEditFieldLabel = uilabel(app.UIFigure);
            app.AccountNumberEditFieldLabel.Text = 'Account Number';
            app.AccountNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.AccountNumberEditField.ValueChangedFcn = createCallbackFcn(app, @AccountNumberEditFieldValueChanged, true);
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ActionTextAreaLabel = uilabel(app.UIFigure);
            app.ActionTextAreaLabel.Text = 'Action';
            app.ActionTextArea = uitextarea(app.UIFigure);
            app.ActionTextArea.ValueChangedFcn = createCallbackFcn(app, @ActionTextAreaValueChanged, true);
            app.FaceIdentifiedTextAreaLabel = uilabel(app.UIFigure);
            app.FaceIdentifiedTextAreaLabel.Text = 'Face Identified';
            app.FaceIdentifiedTextArea = uitextarea(app.UIFigure);
            app.FaceIdentifiedTextArea.ValueChangedFcn = createCallbackFcn(app, @FaceIdentifiedTextAreaValueChanged, true);
            app.SECURETRANSACTIONSPORTALLabel = uilabel(app.UIFigure);
            app.SECURETRANSACTIONSPORTALLabel.Text = 'SECURE TRANSACTIONS PORTAL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end